classdef high_speed_daq < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VoltageMeter                   matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ConnectionTab                  matlab.ui.container.Tab
        DeviceListDropDownLabel        matlab.ui.control.Label
        DeviceListDropDown             matlab.ui.control.DropDown
        ConnectButton                  matlab.ui.control.Button
        WaitforconnectionLabel         matlab.ui.control.Label
        CaptureTab                     matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        AutoScaleButton                matlab.ui.control.Button
        ExportButton                   matlab.ui.control.Button
        DebugButton                    matlab.ui.control.Button
        ParametersPanel                matlab.ui.container.Panel
        MHzf0100MHzLabel               matlab.ui.control.Label
        SamplingfrequencyMHzEditFieldLabel  matlab.ui.control.Label
        SamplingfrequencyMHzEditField  matlab.ui.control.NumericEditField
        ActualFrequencyMHzLabel        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        SamplingdepthEditFieldLabel    matlab.ui.control.Label
        SamplingdepthEditField         matlab.ui.control.NumericEditField
        TriggerPanel                   matlab.ui.container.Panel
        CaptureImmidiatelyButton       matlab.ui.control.Button
        TriggerFromexternalButton      matlab.ui.control.Button
        EditField                      matlab.ui.control.NumericEditField
        VU5VLabel                      matlab.ui.control.Label
        ArmButton                      matlab.ui.control.Button
        UVDropDownLabel                matlab.ui.control.Label
        UVDropDown                     matlab.ui.control.DropDown
        CheckBufferButton              matlab.ui.control.Button
        DataavailabilityLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
       InstrumentObject ; % the serial port that retrieve the data
       resolution=10/2^12;
       sampling_frequency=5; 
      
       Continue % Description
       frequency_array=[]; % Description
       base_frequency=500;
       samplingdepth=256; % Description
       parameterupload=0;
       trigger_threshold=0;
    end
    
    properties (Access = public)
        time; 
        A;
        B;
       % buffer for channels
       bufferA=[];
       %buffer for plotting
       bufferB=[];
       row_index=[];
        A_mean=2036;
        B_mean=2045;
        % Description
    end


    methods (Access = private)
        
        function ChannelRegister(app)
            if (app.A0ChannelCheckBox.Value==1)
               app.Channel0P=0;
               app.Channel0N=1;
           else
               app.Channel0P=[];
               app.Channel0N=[];
            end
            
            if (app.A1ChannelCheckBox.Value==1)
               app.Channel1P=16*2;
               app.Channel1N=3;
           else
               app.Channel1P=[];
               app.Channel1N=[];
            end
            
            if (app.A2ChannelCheckBox.Value==1)
               app.Channel2P=16*4;
               app.Channel2N=5;
           else
               app.Channel2P=[];
               app.Channel2N=[];
            end
            
            if (app.A3ChannelCheckBox.Value==1)
               app.Channel3P=16*6;
               app.Channel3N=7;
           else
               app.Channel3P=[];
               app.Channel3N=[];
            end
            
         end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         % Find all available instruments
           ports=seriallist;
         % initial all buffers
%            app.bufferA = NaN(4,app.bufferSizeA);
%            app.bufferB = NaN(4,app.bufferSizeB);
           app.DeviceListDropDown.Items=ports; 
           set(app.UIAxes,'linewidth',1,'FontSize',20)
           app.UIAxes.XLabel.String='Counts';
           app.UIAxes.YLabel.String='Voltage (V)';
           
           
           % yyaxis(app.UIAxes,'right')
           % app.UIAxes.YLabel.String='Current (A)';
           
            assignin('base', 'sub_volt', app);
           
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % Get the selected item from the dropdown
            
            obj=serial(app.DeviceListDropDown.Value,'BaudRate', 115200);
            app.InstrumentObject=obj;
            
            set(app.InstrumentObject, 'InputBufferSize', 5000000);
           % Open the serial port
            fopen(app.InstrumentObject);
            app.WaitforconnectionLabel.Text='Connected';
            
        end

        % Close request function: VoltageMeter
        function VoltageMeterCloseRequest(app, event)
            s= instrfind;
           if isempty(s)
           else
           fclose(s);
           delete(s);
            clear s;
           end
            delete(app)
            
        end

        % Button pushed function: CaptureImmidiatelyButton
        function CaptureImmidiatelyButtonPushed(app, event)
         
   
           % if the dma is not issued
            if (app.parameterupload==0)
                % send to fpga, issue the dma
                fwrite(app.InstrumentObject,app.sampling_frequency,'uint8');
                fwrite(app.InstrumentObject, app.samplingdepth*2, 'uint16');
                app.parameterupload=1;
            end
           % trigger immediately
           fwrite(app.InstrumentObject,3,'uint8');
            fwrite(app.InstrumentObject, 0, 'uint16');
           %  wait the data
           
           pause(0.1)
           bytes=0;
           while (app.InstrumentObject.BytesAvailable-bytes~=0)
               bytes=app.InstrumentObject.BytesAvailable;
               pause(0.1)


           end
            
            % retrieve the data
             rawData = fread(app.InstrumentObject, app.InstrumentObject.BytesAvailable/2, "uint16");
             rawData=rawData(3:end);
             % generate the time data
             app.time=(0:1:(length(rawData)-1))./app.sampling_frequency.*1000;  
             voltage=rawData.*app.resolution-5;
             % plot
             plot(app.UIAxes, app.time, voltage, 'b-', 'LineWidth', 2);
             ylabel(app.UIAxes, 'Voltage (volts)');
             xlabel(app.UIAxes, 'Time (nanosecond)')
             
             % clear the flag
             app.parameterupload=0;
             % un trigger the input fifo
             % fwrite(app.InstrumentObject,0,'uint8');
             % fwrite(app.InstrumentObject, 0, 'uint16');


        end

        % Callback function
        function A0VEditFieldValueChanged(app, event)
           
            value = app.A0VEditField.Value;
            if (value>1/(str2double(app.VoltageRangeDropDown.Value)*1e6)*app.Maxpoints*1e3)
                app.A0VEditField.Value=1/(str2double(app.VoltageRangeDropDown.Value)*1e6)*app.Maxpoints*1e3;
            end
                
        end

        % Callback function
        function AutoButtonPushed(app, event)
            if (app.InstrumentObject.BytesAvailable>0)
                fread(app.InstrumentObject, app.InstrumentObject.BytesAvailable);
            end
            
            
            sysfrequency=125;
            period=1/(str2double(app.VoltageRangeDropDown.Value)*1e6);
            div=sysfrequency/str2double(app.VoltageRangeDropDown.Value);
            length_of_buf= uint16(512);
            app.Continue=1;
            while (app.Continue==1)
                fwrite(app.InstrumentObject,div,'uint8');
                fwrite(app.InstrumentObject,length_of_buf,'uint16');
                
                dataStr = fscanf(app.InstrumentObject, '%s');  % Read all incoming data as a string
                dataLen=length(dataStr);
                % Split the string into 8-character groups (each representing one uint32)
                numGroups = dataLen / 8;
                receivedData = reshape(dataStr, 8, numGroups)';  % Reshape into a matrix where each row is 8 characters
                
                % Convert each group of 8 characters from hex string to uint32
                uint32Values = uint32(hex2dec(receivedData));
                % Initialize arrays to store the split data
                
                [app.A, app.B] = app.extractData(uint32Values);
                app.time=0:period:period*double((length_of_buf-1));
                
                
                x=app.time;
                cla(app.UIAxes)
                yyaxis(app.UIAxes,'left')
                plot(app.UIAxes,x,double(app.A)-app.A_mean)
                hold(app.UIAxes,'on')
                yyaxis(app.UIAxes,'right')
                plot(app.UIAxes,x,double(app.B)-app.B_mean)
                hold(app.UIAxes,'off')
                pause(0.1); drawnow;
                 
                  if (app.InstrumentObject.BytesAvailable>0)
                fread(app.InstrumentObject, app.InstrumentObject.BytesAvailable);
                  end
           
            end
            
                
        end

        % Button pushed function: AutoScaleButton
        function AutoScaleButtonPushed(app, event)
            axis(app.UIAxes, 'tight'); 
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
             keyboard
        end

        % Callback function
        function A1ChannelCheckBoxValueChanged(app, event)
            value = app.A1ChannelCheckBox.Value;
            if (value==1)
               app.Channel1P=32;
               app.Channel1N=3;
           else
               app.Channel1P=[];
               app.Channel1N=[];
           end
        end

        % Callback function
        function A2ChannelCheckBoxValueChanged(app, event)
            value = app.A2ChannelCheckBox.Value;
            if (value==1)
               app.Channel1P=16*4;
               app.Channel1N=5;
           else
               app.Channel1P=[];
               app.Channel1N=[];
           end
        end

        % Callback function
        function A3ChannelCheckBoxValueChanged(app, event)
            value = app.A3ChannelCheckBox.Value;
            if (value==1)
               app.Channel1P=16*6;
               app.Channel1N=7;
           else
               app.Channel1P=[];
               app.Channel1N=[];
           end
        end

        % Callback function
        function STOPButtonPushed(app, event)
            % write 3 0 bytes to stm32 to stop capturing
            fwrite(app.InstrumentObject, [0, 0, 0], "uint8");
            % set continue as 0
            app.Continue=0;
             if (app.InstrumentObject.BytesAvailable>0)
                fread(app.InstrumentObject, app.InstrumentObject.BytesAvailable);
             end
        end

        % Callback function
        function RescaleButtonPushed(app, event)
            %clear 
%              app.bufferIndexA = 1;  % Write index for buffer A
%              app.bufferIndexB = 1;
%              app.bufferA = NaN(4,app.bufferSizeA);
%              app.bufferB = NaN(4,app.bufferSizeB);

           xlimits=app.UIAxes.XLim;
           app.SamplingfrequencyMHzEditField.Value=xlimits(1);
           app.initial_frequency=xlimits(1);
           
           app.SamplingdepthEditField.Value=xlimits(2);
           app.Final_Frequency=xlimits(2);
           
             
        end

        % Value changed function: SamplingfrequencyMHzEditField
        function SamplingfrequencyMHzEditFieldValueChanged(app, event)
           
            value = app.SamplingfrequencyMHzEditField.Value;
            
             if value>100
                app.SamplingfrequencyMHzEditField.Value=100;
            end
            
            if value<5
                app.SamplingfrequencyMHzEditField.Value=5;
            end
            value = app.SamplingfrequencyMHzEditField.Value;
            
            divider_int=floor(app.base_frequency/value);
            % the fraction part is a multiple of 1/8
            divider_frac=floor((app.base_frequency/value-divider_int)/0.125)*0.125;
            divider=divider_int+divider_frac;
            
            app.sampling_frequency=app.base_frequency/divider;
            
            app.Label.Text=num2str(app.sampling_frequency);
        end

        % Value changed function: SamplingdepthEditField
        function SamplingdepthEditFieldValueChanged(app, event)
            value = floor(app.SamplingdepthEditField.Value);
            
            if value>2^15
                app.SamplingdepthEditField.Value=2^15;
            end
            
            if value<0
                app.SamplingdepthEditField.Value=2;
            end
            
            app.samplingdepth=floor(app.SamplingdepthEditField.Value/2)*2;


           % app.Final_Frequency=value;
        end

        % Callback function
        function SetthecentralfrequecytolockButtonPushed(app, event)
             xlimits=app.UIAxes.XLim;
             app.LockinatFrequencyEditField.Value=(xlimits(1)+xlimits(2))/2;
             app.lockin_frequency= app.LockinatFrequencyEditField.Value;
             
        end

        % Callback function
        function GetPhaseSetpointButtonPushed(app, event)
            % initial frequency for sweep, uint 32
           initial_fre=app.scalar*app.lockin_frequency;
           % clock cycles for each frequency, uint16
           cycles=2^16-1;
           % compute for step frequency, uint 32
           step=(app.Final_Frequency-app.initial_frequency)*app.scalar/255;
           % mode selection, uint 8, sweep=0, pll=255
           sweep=255;
           
           % send to fpga
           fwrite(app.InstrumentObject,0,'uint32');
           fwrite(app.InstrumentObject, 0, 'uint16');
           fwrite(app.InstrumentObject, initial_fre, 'uint32');   
           fwrite(app.InstrumentObject, sweep, 'uint8');  
            pause(1);
            % retrieve the data
            if (app.InstrumentObject.BytesAvailable==16)
           rawData = fread(app.InstrumentObject, app.InstrumentObject.BytesAvailable/4, "uint32");
            else
                keyboard
            end


             % compute i and q components
           i_q_components=rawData./(128^2*(2^16-1))-1;
           % reshape, 1 col is i, 2nd col is qs
           i_q_components =reshape(i_q_components,2,length(rawData)/2);
           
           % Given a 2xN array 'iq_data'
            i_components = i_q_components(1, 2);  % Row 1 = I
            q_components = i_q_components(2, 2);  % Row 2 = Q

            % Compute magnitude for each I/Q pair
            % magnitude = sqrt(i_components.^2 + q_components.^2);
            phase_rad = atan2(i_components, q_components);
            
            % Convert to degrees (optional)
            phase_deg = rad2deg(phase_rad);
            
            app.SetpointDegreeEditField.Value=phase_deg;
            app.setpoint_phase=phase_deg;


        end

        % Callback function
        function LockInButtonPushed(app, event)
            app.Continue=1;
            initial_fre=app.scalar*app.lockin_frequency;
            while(app.Continue==1)
                
           % initial frequency for sweep, uint 32
           
           % clock cycles for each frequency, uint16
           cycles=2^16-1;
           % compute for step frequency, uint 32
           step=(app.Final_Frequency-app.initial_frequency)*app.scalar/255;
           % mode selection, uint 8, sweep=0, pll=255
           sweep=255;
           
           % send to fpga
           fwrite(app.InstrumentObject,0,'uint32');
           
           fwrite(app.InstrumentObject, 0, 'uint16');
           
           fwrite(app.InstrumentObject, initial_fre, 'uint32');   
          
           fwrite(app.InstrumentObject, sweep, 'uint8');  
            pause(0.1);
            % retrieve the data
            if (app.InstrumentObject.BytesAvailable==16)
           rawData = fread(app.InstrumentObject, app.InstrumentObject.BytesAvailable/4, "uint32");
            else
                keyboard
            end


             % compute i and q components
           i_q_components=rawData./(128^2*(2^16-1))-1;
           % reshape, 1 col is i, 2nd col is qs
           i_q_components =reshape(i_q_components,2,length(rawData)/2);
           
           % Given a 2xN array 'iq_data'
            i_components = i_q_components(1, 2);  % Row 1 = I
            q_components = i_q_components(2, 2);  % Row 2 = Q

            % Compute magnitude for each I/Q pair
            % magnitude = sqrt(i_components.^2 + q_components.^2);
            phase_rad = atan2(i_components, q_components);
            
            
            % Convert to degrees (optional)
            phase_deg = rad2deg(phase_rad);


            % lock loop
            if (abs(phase_deg-app.setpoint_phase)<3)
                initial_fre=initial_fre+app.PLL_gain*(phase_deg-app.setpoint_phase)*100;
%             else
%                % fill the input buffer with 10 bytes
%                fwrite(app.InstrumentObject, app.dumy, 'uint8');
%                pause(0.1)
%                fread(app.InstrumentObject,app.InstrumentObject.BytesAvailable,'uint8');
            end


            % assign the frequency tuning word back to the array
            app.frequency_array=[app.frequency_array;initial_fre/app.scalar];
            plot(app.UIAxes_2, app.frequency_array, 'b-', 'LineWidth', 2);
            ylabel(app.UIAxes_2, 'Frequency (Hz)');
            
            pause(0.1)  
             drawnow   
             
                
            end
        end

        % Callback function
        function STOPButtonPushed2(app, event)
            app.Continue=0;
        end

        % Callback function
        function GainEditFieldValueChanged(app, event)
            app.PLL_gain = app.GainEditField.Value;
            
        end

        % Callback function
        function ClearButtonPushed(app, event)
            app.frequency_array=[];
        end

        % Value changed function: DeviceListDropDown
        function DeviceListDropDownValueChanged(app, event)
            value = app.DeviceListDropDown.Value;
            
        end

        % Button pushed function: CheckBufferButton
        function CheckBufferButtonPushed(app, event)
            if (app.InstrumentObject.BytesAvailable==0)
                app.DataavailabilityLabel.Text='Not triggerred';
            else
                app.DataavailabilityLabel.Text='Triggerred';
                %waiting the complete of data
                pause(0.1)
                bytes=0;
                while (app.InstrumentObject.BytesAvailable-bytes~=0)
                    bytes=app.InstrumentObject.BytesAvailable;
                    pause(0.1)


                end
                
                % retrieve the data
                rawData = fread(app.InstrumentObject, app.InstrumentObject.BytesAvailable/2, "uint16");
                rawData=rawData(3:end);
                % generate the time data
                app.time=(0:1:(length(rawData)-1))./app.sampling_frequency.*1000;
                voltage=rawData.*app.resolution-5;
                % plot
                plot(app.UIAxes, app.time, voltage, 'b-', 'LineWidth', 2);
                ylabel(app.UIAxes, 'Voltage (volts)');
                xlabel(app.UIAxes, 'Time (nanosecond)')
                
                % clear the flag
                app.parameterupload=0;
                
                % un trigger the input fifo
                % fwrite(app.InstrumentObject,0,'uint8');
                % fwrite(app.InstrumentObject, 0, 'uint16');


            end
        end

        % Button pushed function: TriggerFromexternalButton
        function TriggerFromexternalButtonPushed(app, event)
            % if the dma is not issued
            if (app.parameterupload==0)
                % send to fpga, issue the dma
                fwrite(app.InstrumentObject,app.sampling_frequency,'uint8');
                fwrite(app.InstrumentObject, app.samplingdepth*2, 'uint16');
                app.parameterupload=1;
            end
            
            
            % trigger using external hardware
            fwrite(app.InstrumentObject,4,'uint8');
            fwrite(app.InstrumentObject, 0, 'uint16');
     
             
        end

        % Button pushed function: ArmButton
        function ArmButtonPushed(app, event)
                % if the dma is not issued
            if (app.parameterupload==0)
                % send to fpga, issue the dma
                fwrite(app.InstrumentObject,app.sampling_frequency,'uint8');
                fwrite(app.InstrumentObject, app.samplingdepth*2, 'uint16');
                app.parameterupload=1;
            end
            
            
            % trigger using threshold hardware
            % larger or less than using the dropdown value
            if (app.UVDropDown.Value=='>')
                fwrite(app.InstrumentObject,1,'uint8');
            else
                fwrite(app.InstrumentObject,2,'uint8'); 
            end
            app.trigger_threshold=app.EditField.Value;
            % convert to uint12
            app.trigger_threshold=floor((app.trigger_threshold+5)/(10/2^12));
            % send the threshold to fpga
            fwrite(app.InstrumentObject, app.trigger_threshold, 'uint16');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VoltageMeter = uifigure;
            app.VoltageMeter.Name = 'High speed DAQ';
            app.VoltageMeter.CloseRequestFcn = createCallbackFcn(app, @VoltageMeterCloseRequest, true);
            app.TabGroup = uitabgroup(app.VoltageMeter);
            app.ConnectionTab = uitab(app.TabGroup);
            app.ConnectionTab.Title = 'Connection';
            app.DeviceListDropDownLabel = uilabel(app.ConnectionTab);
            app.DeviceListDropDownLabel.Text = 'Device List';
            app.DeviceListDropDown = uidropdown(app.ConnectionTab);
            app.DeviceListDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceListDropDownValueChanged, true);
            app.ConnectButton = uibutton(app.ConnectionTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.WaitforconnectionLabel = uilabel(app.ConnectionTab);
            app.WaitforconnectionLabel.Text = 'Wait for connection';
            app.CaptureTab = uitab(app.TabGroup);
            app.CaptureTab.Title = 'Capture';
            app.UIAxes = uiaxes(app.CaptureTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Voltage')
            app.AutoScaleButton = uibutton(app.CaptureTab, 'push');
            app.AutoScaleButton.ButtonPushedFcn = createCallbackFcn(app, @AutoScaleButtonPushed, true);
            app.AutoScaleButton.Text = 'Auto Scale';
            app.ExportButton = uibutton(app.CaptureTab, 'push');
            app.ExportButton.Text = {'Export'; ''};
            app.DebugButton = uibutton(app.CaptureTab, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Text = {'Debug'; ''};
            app.ParametersPanel = uipanel(app.CaptureTab);
            app.ParametersPanel.Title = 'Parameters';
            app.MHzf0100MHzLabel = uilabel(app.ParametersPanel);
            app.MHzf0100MHzLabel.Text = '5 MHz<f0<100 MHz';
            app.SamplingfrequencyMHzEditFieldLabel = uilabel(app.ParametersPanel);
            app.SamplingfrequencyMHzEditFieldLabel.Text = 'Sampling frequency (MHz)';
            app.SamplingfrequencyMHzEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SamplingfrequencyMHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingfrequencyMHzEditFieldValueChanged, true);
            app.SamplingfrequencyMHzEditField.Value = 5;
            app.ActualFrequencyMHzLabel = uilabel(app.ParametersPanel);
            app.ActualFrequencyMHzLabel.Text = 'Actual Frequency (MHz)';
            app.Label = uilabel(app.ParametersPanel);
            app.Label.Text = '5';
            app.SamplingdepthEditFieldLabel = uilabel(app.ParametersPanel);
            app.SamplingdepthEditFieldLabel.Text = 'Sampling depth';
            app.SamplingdepthEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SamplingdepthEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingdepthEditFieldValueChanged, true);
            app.SamplingdepthEditField.Value = 256;
            app.TriggerPanel = uipanel(app.CaptureTab);
            app.TriggerPanel.Title = 'Trigger';
            app.CaptureImmidiatelyButton = uibutton(app.TriggerPanel, 'push');
            app.CaptureImmidiatelyButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureImmidiatelyButtonPushed, true);
            app.CaptureImmidiatelyButton.Text = 'Capture Immidiately';
            app.TriggerFromexternalButton = uibutton(app.TriggerPanel, 'push');
            app.TriggerFromexternalButton.ButtonPushedFcn = createCallbackFcn(app, @TriggerFromexternalButtonPushed, true);
            app.TriggerFromexternalButton.Text = 'Trigger From external';
            app.EditField = uieditfield(app.TriggerPanel, 'numeric');
            app.VU5VLabel = uilabel(app.TriggerPanel);
            app.VU5VLabel.Text = '-5 V<U<5 V';
            app.ArmButton = uibutton(app.TriggerPanel, 'push');
            app.ArmButton.ButtonPushedFcn = createCallbackFcn(app, @ArmButtonPushed, true);
            app.ArmButton.Text = 'Arm';
            app.UVDropDownLabel = uilabel(app.TriggerPanel);
            app.UVDropDownLabel.Text = 'U(V)';
            app.UVDropDown = uidropdown(app.TriggerPanel);
            app.UVDropDown.Items = {'<', '>'};
            app.UVDropDown.Value = '<';
            app.CheckBufferButton = uibutton(app.CaptureTab, 'push');
            app.CheckBufferButton.ButtonPushedFcn = createCallbackFcn(app, @CheckBufferButtonPushed, true);
            app.CheckBufferButton.Text = 'Check Buffer';
            app.DataavailabilityLabel = uilabel(app.CaptureTab);
            app.DataavailabilityLabel.Text = 'Data availability';
        end
    end

    methods (Access = public)

        % Construct app
        function app = high_speed_daq

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VoltageMeter)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VoltageMeter)
        end
    end
end